classdef FilamentWinding < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        ClearLayerButton                matlab.ui.control.Button
        FilamentPropertiesLabel         matlab.ui.control.Label
        DesignParametersLabel           matlab.ui.control.Label
        VesselGeometryLabel             matlab.ui.control.Label
        PressureEditField               matlab.ui.control.EditField
        PressureEditFieldLabel          matlab.ui.control.Label
        FilamentStrengthEditField       matlab.ui.control.EditField
        FilamentStrengthEditFieldLabel  matlab.ui.control.Label
        FilamentWidthEditField          matlab.ui.control.EditField
        FilamentWidthEditFieldLabel     matlab.ui.control.Label
        FilamentThicknessEditField      matlab.ui.control.EditField
        FilamentThicknessEditFieldLabel  matlab.ui.control.Label
        SafetyFactorEditField           matlab.ui.control.EditField
        SafetyFactorEditFieldLabel      matlab.ui.control.Label
        InletDiameterEditField          matlab.ui.control.EditField
        InletDiameterEditFieldLabel     matlab.ui.control.Label
        DiameterEditField               matlab.ui.control.EditField
        DiameterEditFieldLabel          matlab.ui.control.Label
        MoldShapeButtonGroup            matlab.ui.container.ButtonGroup
        CylinderwithCapsButton          matlab.ui.control.RadioButton
        CylinderButton                  matlab.ui.control.RadioButton
        SphereButton                    matlab.ui.control.RadioButton
        AnimateButton                   matlab.ui.control.Button
        ChangeVIewButton                matlab.ui.control.Button
        AddLayerButton                  matlab.ui.control.Button
        UITable2                        matlab.ui.control.Table
        CircuitsEditField               matlab.ui.control.EditField
        CircuitsEditFieldLabel          matlab.ui.control.Label
        WindAngleEditField              matlab.ui.control.EditField
        WindAngleEditFieldLabel         matlab.ui.control.Label
        WindTypeButtonGroup             matlab.ui.container.ButtonGroup
        HoopButton                      matlab.ui.control.RadioButton
        PolarButton                     matlab.ui.control.RadioButton
        HelicalButton                   matlab.ui.control.RadioButton
        UIAxes2                         matlab.ui.control.UIAxes
        UIAxes                          matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        alpha %wind angle (radians)
        S_F %fiber strength
        P %pressure
        N %safety factor
        D % Diameter
        R %Radius
        w %filament width
        t %filament thickness
        r_0 %starting radius
        d_0 %starting diameter
        n_pts %number of points
        g % g-code
        G % surface mold array
        alpha_helical % helical wind angle
        i %index


    end
    
    methods (Access = private)
        
        function [n_hoop, n_helix] = Netting_Analysis(app)
            S_D = app.S_F / app.N; %design strength
            N_hoop = app.P * app.R; %hoop force
            N_axial = N_hoop / 2; %axial force
            
            t_helical = N_axial / (S_D * cos(app.alpha)^2); %hoop thickness
            t_hoop = (N_hoop - N_axial * tan(app.alpha)^2) / S_D; %helical thickness
            
            n_hoop = t_hoop / app.t; %returns number of hoop wraps
            n_helix = t_helical / app.t; %returns number of helix wraps
        end
        
        function plot3D(app)
            cla(app.UIAxes_2)
            plot3d(app.UIAxes_2, app.g(1,:), app.g(2,:), app.g(3,:)) %plots x,y,z filament 
            
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            
            app.R = app.D / 2;
        end

        % Value changed function: DiameterEditField
        function DiameterEditFieldValueChanged(app, event)
            app.D = app.DiameterEditField.Value;
            app.R = app.D / 2;
            
        end

        % Value changed function: InletDiameterEditField
        function InletDiameterEditFieldValueChanged(app, event)
            app.d_0 = app.InletDiameterEditField.Value;
            app.r_0 = app.d_0 / 2;
            
        end

        % Value changed function: SafetyFactorEditField
        function SafetyFactorEditFieldValueChanged(app, event)
            app.N = app.SafetyFactorEditField.Value;
            
        end

        % Value changed function: PressureEditField
        function PressureEditFieldValueChanged(app, event)
            app.P = app.PressureEditField.Value;
            
        end

        % Value changed function: FilamentThicknessEditField
        function FilamentThicknessEditFieldValueChanged(app, event)
            app.t = app.FilamentThicknessEditField.Value;
            
        end

        % Value changed function: FilamentWidthEditField
        function FilamentWidthEditFieldValueChanged(app, event)
            app.w = app.FilamentWidthEditField.Value;
            
        end

        % Value changed function: FilamentStrengthEditField
        function FilamentStrengthEditFieldValueChanged(app, event)
            app.S_F = app.FilamentStrengthEditField.Value;
            
        end

        % Selection changed function: MoldShapeButtonGroup
        function MoldShapeButtonGroupSelectionChanged(app, event)
            selectedButton = app.MoldShapeButtonGroup.SelectedObject;
            
        end

        % Button pushed function: AddLayerButton
        function AddLayerButtonPushed(app, event)
            alpha = app.WindAngleEditField.Value;
            circuits = app.CircuitsEditField.Value;
            
            new_col = {alpha; circuits};
            app.UITable2.Data = [app.UITable2.Data, new_col]; %adds the labels to the table as new row
        end

        % Cell selection callback: UITable2
        function UITable2CellSelection(app, event)
            app.i = event.Indices; 
     
        end

        % Button pushed function: ClearLayerButton
        function ClearLayerButtonPushed(app, event)

            app.UITable2.Data(:,app.i(1,2)) = [];
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Current Layer')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes2 = uiaxes(app.UIFigure);
            title(app.UIAxes2, 'Current Layer Animation')
            xlabel(app.UIAxes2, 'X')
            ylabel(app.UIAxes2, 'Y')
            zlabel(app.UIAxes2, 'Z')
            app.WindTypeButtonGroup = uibuttongroup(app.UIFigure);
            app.WindTypeButtonGroup.Title = 'Wind Type';
            app.HelicalButton = uiradiobutton(app.WindTypeButtonGroup);
            app.HelicalButton.Text = 'Helical';
            app.HelicalButton.Value = true;
            app.PolarButton = uiradiobutton(app.WindTypeButtonGroup);
            app.PolarButton.Text = 'Polar';
            app.HoopButton = uiradiobutton(app.WindTypeButtonGroup);
            app.HoopButton.Text = 'Hoop';
            app.WindAngleEditFieldLabel = uilabel(app.UIFigure);
            app.WindAngleEditFieldLabel.Text = 'Wind Angle';
            app.WindAngleEditField = uieditfield(app.UIFigure, 'text');
            app.CircuitsEditFieldLabel = uilabel(app.UIFigure);
            app.CircuitsEditFieldLabel.Text = 'Circuits';
            app.CircuitsEditField = uieditfield(app.UIFigure, 'text');
            app.UITable2 = uitable(app.UIFigure);
            app.UITable2.ColumnName = {'1st Layer'; '2nd Layer'; '3rd Layer'; '4th Layer'; '5th Layer'; '6th Layer'; '7th Layer'; '8th Layer'; '9th Layer'; '10th Layer'; '11th Layer'; '12th Layer'; '13th Layer'; '14th Layer'; '15th Layer'; '16th Layer'; '17th Layer'; '18th Layer'; '19th Layer'; '20th Layer'};
            app.UITable2.RowName = {};
            app.UITable2.CellSelectionCallback = createCallbackFcn(app, @UITable2CellSelection, true);
            app.AddLayerButton = uibutton(app.UIFigure, 'push');
            app.AddLayerButton.ButtonPushedFcn = createCallbackFcn(app, @AddLayerButtonPushed, true);
            app.AddLayerButton.Text = 'Add Layer';
            app.ChangeVIewButton = uibutton(app.UIFigure, 'push');
            app.ChangeVIewButton.Text = 'Change VIew';
            app.AnimateButton = uibutton(app.UIFigure, 'push');
            app.AnimateButton.Text = 'Animate';
            app.MoldShapeButtonGroup = uibuttongroup(app.UIFigure);
            app.MoldShapeButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @MoldShapeButtonGroupSelectionChanged, true);
            app.MoldShapeButtonGroup.Title = 'Mold Shape';
            app.SphereButton = uiradiobutton(app.MoldShapeButtonGroup);
            app.SphereButton.Text = 'Sphere';
            app.SphereButton.Value = true;
            app.CylinderButton = uiradiobutton(app.MoldShapeButtonGroup);
            app.CylinderButton.Text = 'Cylinder';
            app.CylinderwithCapsButton = uiradiobutton(app.MoldShapeButtonGroup);
            app.CylinderwithCapsButton.Text = 'Cylinder with Caps';
            app.DiameterEditFieldLabel = uilabel(app.UIFigure);
            app.DiameterEditFieldLabel.Text = 'Diameter';
            app.DiameterEditField = uieditfield(app.UIFigure, 'text');
            app.DiameterEditField.ValueChangedFcn = createCallbackFcn(app, @DiameterEditFieldValueChanged, true);
            app.InletDiameterEditFieldLabel = uilabel(app.UIFigure);
            app.InletDiameterEditFieldLabel.Text = 'Inlet Diameter';
            app.InletDiameterEditField = uieditfield(app.UIFigure, 'text');
            app.InletDiameterEditField.ValueChangedFcn = createCallbackFcn(app, @InletDiameterEditFieldValueChanged, true);
            app.SafetyFactorEditFieldLabel = uilabel(app.UIFigure);
            app.SafetyFactorEditFieldLabel.Text = 'Safety Factor';
            app.SafetyFactorEditField = uieditfield(app.UIFigure, 'text');
            app.SafetyFactorEditField.ValueChangedFcn = createCallbackFcn(app, @SafetyFactorEditFieldValueChanged, true);
            app.FilamentThicknessEditFieldLabel = uilabel(app.UIFigure);
            app.FilamentThicknessEditFieldLabel.Text = 'Filament Thickness';
            app.FilamentThicknessEditField = uieditfield(app.UIFigure, 'text');
            app.FilamentThicknessEditField.ValueChangedFcn = createCallbackFcn(app, @FilamentThicknessEditFieldValueChanged, true);
            app.FilamentWidthEditFieldLabel = uilabel(app.UIFigure);
            app.FilamentWidthEditFieldLabel.Text = 'Filament Width';
            app.FilamentWidthEditField = uieditfield(app.UIFigure, 'text');
            app.FilamentWidthEditField.ValueChangedFcn = createCallbackFcn(app, @FilamentWidthEditFieldValueChanged, true);
            app.FilamentStrengthEditFieldLabel = uilabel(app.UIFigure);
            app.FilamentStrengthEditFieldLabel.Text = 'Filament Strength';
            app.FilamentStrengthEditField = uieditfield(app.UIFigure, 'text');
            app.FilamentStrengthEditField.ValueChangedFcn = createCallbackFcn(app, @FilamentStrengthEditFieldValueChanged, true);
            app.PressureEditFieldLabel = uilabel(app.UIFigure);
            app.PressureEditFieldLabel.Text = 'Pressure';
            app.PressureEditField = uieditfield(app.UIFigure, 'text');
            app.PressureEditField.ValueChangedFcn = createCallbackFcn(app, @PressureEditFieldValueChanged, true);
            app.VesselGeometryLabel = uilabel(app.UIFigure);
            app.VesselGeometryLabel.Text = 'Vessel Geometry';
            app.DesignParametersLabel = uilabel(app.UIFigure);
            app.DesignParametersLabel.Text = 'Design Parameters';
            app.FilamentPropertiesLabel = uilabel(app.UIFigure);
            app.FilamentPropertiesLabel.Text = 'Filament Properties';
            app.ClearLayerButton = uibutton(app.UIFigure, 'push');
            app.ClearLayerButton.ButtonPushedFcn = createCallbackFcn(app, @ClearLayerButtonPushed, true);
            app.ClearLayerButton.Text = 'Clear Layer';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = FilamentWinding

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end